classdef Trisyllabic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Fixation          matlab.ui.control.Image
        ReactionDropDown  matlab.ui.control.DropDown
        TriggerDropDown   matlab.ui.control.DropDown
        QCheckBox3        matlab.ui.control.CheckBox
        QCheckBox2        matlab.ui.control.CheckBox
        QCheckBox1        matlab.ui.control.CheckBox
        QSubmit           matlab.ui.control.Button
        QLabel            matlab.ui.control.Label
        Title             matlab.ui.control.Label
        Description       matlab.ui.control.TextArea
        Lamp3             matlab.ui.control.Lamp
        Lamp2             matlab.ui.control.Lamp
        Lamp1             matlab.ui.control.Lamp
        RecordingIDLabel  matlab.ui.control.Label
        Redo2Button       matlab.ui.control.Button
        Block2Button      matlab.ui.control.Button
        Redo3Button       matlab.ui.control.Button
        Block3Button      matlab.ui.control.Button
        TextLabel         matlab.ui.control.Label
        Instruction       matlab.ui.control.Label
        Redo1Button       matlab.ui.control.Button
        Block1Button      matlab.ui.control.Button
    end

    
    properties (Access = private)
        State = 0;
        Name = 'Trisyllabic';
        Version = '1.4';
        BlocksDone = [false, false, false];
        BlocksSound = {};
        BlocksTrigger = {};
        BlocksFs = {};
        RecordingID = string(datetime('now', 'Format', 'yyyy-MM-dd HH-mm-ss.SSS z', 'TimeZone', 'UTC'));
        Player = 0;
        Interrupt = false;
        Calibrated = false;
        NeedsCalibration = false;
        WaitingForResponse = false;
        
        OriginTime = datetime;
        LastEscape = datetime;
        KeyLog = table([], [], 'VariableNames', {'Block', 'Time'});
        EventLog = table([], {}, [], 'VariableNames', {'Block', 'Event', 'Time'});
        ConfigLog = table([], {}, {}, [], 'VariableNames', {'Block', 'ReactionDevice', 'TriggerMode', 'Time'});
        QuestionLog = table([], [], [], [], [], 'VariableNames', {'Block', 'Option1', 'Option2', 'Option3', 'Time'});
    end
    
    methods (Access = private)
        
        function runBlock(app, block_num)
            if app.State > 0
                return;
            end
            
            app.State = block_num;
            app.updateButtons;

            app.Description.Visible = false;
            app.Interrupt = false;
            
            if app.NeedsCalibration
                app.Calibrated = false;
                app.updateLights;
                
                while ~app.Calibrated
                    pause(0.25);
                    if app.Interrupt
                        app.Calibrated = true;
                        break;
                    end
                end
            else
                app.Calibrated = true;
            end
            
            if ~app.Interrupt
                app.updateLights;
                app.Instruction.Visible = true;
                pause(4);
            end

            app.Instruction.Visible = false;
            if ~app.Interrupt
                app.Fixation.Visible = true;
                pause(1);
            end

            if ~app.Interrupt
                audiodevreset;
                app.Player = audioplayer(app.BlocksSound{block_num}, app.BlocksFs{block_num});
                
                app.registerEvent(block_num, 'Start');
                app.registerConfig(block_num);
                playblocking(app.Player);
            end
            
            if app.Interrupt
                app.Fixation.Visible = false;
                app.Description.Visible = true;
            else
                app.queryBlock;
                
                app.BlocksDone(block_num) = true;
                app.Player = 0;
                app.Interrupt = false;
            end
            
            app.State = 0;
            app.updateButtons;
            app.updateLights;
            app.updateLogs;
        end
        
        function updateButtons(app)
            if app.State > 0
                app.Block1Button.Enable = false;
                app.Block2Button.Enable = false;
                app.Block3Button.Enable = false;
                
                app.Redo1Button.Enable = false;
                app.Redo2Button.Enable = false;
                app.Redo3Button.Enable = false;
                
                app.TriggerDropDown.Enable = false;
                app.ReactionDropDown.Enable = false;
            else
                app.Block1Button.Enable = ~app.BlocksDone(1);
                app.Block2Button.Enable = ~app.BlocksDone(2);
                app.Block3Button.Enable = ~app.BlocksDone(3);
                
                app.Redo1Button.Enable = app.BlocksDone(1);
                app.Redo2Button.Enable = app.BlocksDone(2);
                app.Redo3Button.Enable = app.BlocksDone(3);
                
                app.TriggerDropDown.Enable = true;
                app.ReactionDropDown.Enable = true;
            end
        end
        
        function updateLogs(app)
            event = app.EventLog;
            config = app.ConfigLog;
            keypress = app.KeyLog;
            response = app.QuestionLog;
            save(sprintf('output/%s.mat', app.RecordingID), 'event', 'config', 'keypress', 'response');
        end
        
        function stopBlock(app)
            app.Interrupt = true;
            if app.Player ~= 0
                stop(app.Player);
            end
            app.registerEvent(app.State, 'Stop');
        end
        
        function updateLights(app)
            if app.State == 1 && ~app.Calibrated
                app.Lamp1.Color = [0.07,0.62,1.00];
            elseif app.State == 1 && app.Calibrated
                app.Lamp1.Color = [1.00,0.41,0.16];
            elseif app.BlocksDone(1)
                app.Lamp1.Color = [0.39,0.83,0.07];
            else
                app.Lamp1.Color = [0.8, 0.8, 0.8];
            end
            
            if app.State == 2 && ~app.Calibrated
                app.Lamp2.Color = [0.07,0.62,1.00];
            elseif app.State == 2 && app.Calibrated
                app.Lamp2.Color = [1.00,0.41,0.16];
            elseif app.BlocksDone(2)
                app.Lamp2.Color = [0.39,0.83,0.07];
            else
                app.Lamp2.Color = [0.8, 0.8, 0.8];
            end
            
            if app.State == 3 && ~app.Calibrated
                app.Lamp3.Color = [0.07,0.62,1.00];
            elseif app.State == 3 && app.Calibrated
                app.Lamp3.Color = [1.00,0.41,0.16];
            elseif app.BlocksDone(3)
                app.Lamp3.Color = [0.39,0.83,0.07];
            else
                app.Lamp3.Color = [0.8, 0.8, 0.8];
            end
        end
        
        function queryBlock(app)
            if app.State == 1
                app.QCheckBox1.Text = 'Salary';
                app.QCheckBox2.Text = 'Limited';
                app.QCheckBox3.Text = 'Governor';
            elseif app.State == 2
                app.QCheckBox1.Text = 'Visitor';
                app.QCheckBox2.Text = 'Prominent';
                app.QCheckBox3.Text = 'Relative';
            elseif app.State == 3
                app.QCheckBox1.Text = 'Pacific';
                app.QCheckBox2.Text = 'Traveler';
                app.QCheckBox3.Text = 'Reception';
            end
            
            app.Description.Visible = false;
            app.Fixation.Visible = false;
            app.QLabel.Enable = true;
            app.QLabel.Visible = true;
            app.QCheckBox1.Enable = true;
            app.QCheckBox1.Visible = true;
            app.QCheckBox2.Enable = true;
            app.QCheckBox2.Visible = true;
            app.QCheckBox3.Enable = true;
            app.QCheckBox3.Visible = true;
            app.QSubmit.Enable = true;
            app.QSubmit.Visible = true;
            
            app.registerEvent(app.State, 'Question');
            
            app.WaitingForResponse = true;
            while app.WaitingForResponse
                pause(0.2);
            end
            
            app.QSubmit.Enable = false;
            app.QSubmit.Visible = false;
            app.QLabel.Enable = false;
            app.QLabel.Visible = false;
            app.QCheckBox1.Enable = false;
            app.QCheckBox1.Visible = false;
            app.QCheckBox2.Enable = false;
            app.QCheckBox2.Visible = false;
            app.QCheckBox3.Enable = false;
            app.QCheckBox3.Visible = false;
            app.Description.Visible = true;
            
            app.QCheckBox1.Text = 'Option 1';
            app.QCheckBox1.Value = false;
            app.QCheckBox2.Text = 'Option 2';
            app.QCheckBox2.Value = false;
            app.QCheckBox3.Text = 'Option 3';
            app.QCheckBox3.Value = false;
        end
        
        function registerKeyPress(app)
            time = seconds(datetime-app.OriginTime);
            app.KeyLog(end+1,:) = {app.State, time};
            fprintf('II: Key logged @ %f seconds.\n', time);
        end
        
        function registerEvent(app, block, type)
            time = seconds(datetime-app.OriginTime);
            app.EventLog(end+1,:) = {block, type, time};
            fprintf('II: Event logged @ %f seconds.\n', time);
        end
        
        function registerResponse(app, answer1, answer2, answer3)
            time = seconds(datetime-app.OriginTime);
            app.QuestionLog(end+1,:) = {app.State, answer1, answer2, answer3, time};
            fprintf('II: Response logged @ %f seconds.\n', time);
        end
        
        function registerConfig(app, block)
            time = seconds(datetime-app.OriginTime);
            reactionDevice = app.ReactionDropDown.Value;
            triggerMode = app.TriggerDropDown.Value;
            app.ConfigLog(end+1,:) = {block, reactionDevice, triggerMode, time};
            fprintf('II: Block configuration logged @ %f seconds.\n', time);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            mkdir('output');
            
            app.Title.Text = sprintf('%s (v%s)', app.Name, app.Version);
            if ismac
                os = 'macos';
            elseif isunix
                os = 'linux';
            elseif ispc
                os = 'pc';
            end
            fprintf('>> %s | v%s | matlab-%s\n', app.Name, app.Version, os);

            addpath('resources/image/');
            app.Fixation.ImageSource = 'resources/image/fixation-cross-small.svg';
            
            [app.BlocksSound{1}, app.BlocksFs{1}] = audioread('resources/sound/block1.wav');
            [app.BlocksSound{2}, app.BlocksFs{2}] = audioread('resources/sound/block2.wav');
            [app.BlocksSound{3}, app.BlocksFs{3}] = audioread('resources/sound/block3.wav');
            
            if strcmp(app.TriggerDropDown.Value, 'Audio + Trigger')
                % Add trigger channel to mono audio
                Fs = {};
                [app.BlocksTrigger{1}, Fs{1}] = audioread('resources/sound/block1.trig.wav');
                [app.BlocksTrigger{2}, Fs{2}] = audioread('resources/sound/block2.trig.wav');
                [app.BlocksTrigger{3}, Fs{3}] = audioread('resources/sound/block3.trig.wav');

                for i = 1:3
                    assert(app.BlocksFs{i} == Fs{i});
                    assert(size(app.BlocksSound{i}, 2) == 1);
                    assert(size(app.BlocksTrigger{i}, 2) == 1);
                    assert(size(app.BlocksSound{i}, 1) == size(app.BlocksTrigger{i}, 1));
                end

                app.BlocksSound{1} = cat(2, app.BlocksSound{1}, app.BlocksTrigger{1});
                app.BlocksSound{2} = cat(2, app.BlocksSound{2}, app.BlocksTrigger{2});
                app.BlocksSound{3} = cat(2, app.BlocksSound{3}, app.BlocksTrigger{3});
            end
            
            app.RecordingIDLabel.Text = app.RecordingID;
            
            app.NeedsCalibration = strcmp(app.ReactionDropDown.Value, "Keyboard");
        end

        % Button pushed function: Block1Button
        function Block1ButtonPushed(app, event)
            app.runBlock(1);
        end

        % Button pushed function: Block2Button
        function Block2ButtonPushed(app, event)
            app.runBlock(2);
        end

        % Button pushed function: Block3Button
        function Block3ButtonPushed(app, event)
            app.runBlock(3);
        end

        % Button pushed function: Redo1Button
        function Redo1ButtonPushed(app, event)
            app.runBlock(1);
        end

        % Button pushed function: Redo2Button
        function Redo2ButtonPushed(app, event)
            app.runBlock(2);
        end

        % Button pushed function: Redo3Button
        function Redo3ButtonPushed(app, event)
            app.runBlock(3);
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            reactionDevice = app.ReactionDropDown.Value;
            
            switch key
                case {'space', 'return'}
                    if app.State > 0 && strcmp(reactionDevice, 'Keyboard')
                        if app.Calibrated
                            app.registerKeyPress;
                        else
                            app.Calibrated = true;
                        end
                    end
                case 'escape'
                    if app.State > 0
                        now = datetime;
                        if milliseconds(now - app.LastEscape) < 250
                            app.stopBlock;
                        else
                            app.LastEscape = now;
                        end
                    end
            end
        end

        % Button pushed function: QSubmit
        function QSubmitButtonPushed(app, event)
            app.registerResponse(app.QCheckBox1.Value, app.QCheckBox2.Value, app.QCheckBox3.Value)
            
            app.WaitingForResponse = false;
        end

        % Value changed function: TriggerDropDown
        function TriggerDropDownValueChanged(app, event)
            [app.BlocksSound{1}, app.BlocksFs{1}] = audioread('resources/sound/block1.wav');
            [app.BlocksSound{2}, app.BlocksFs{2}] = audioread('resources/sound/block2.wav');
            [app.BlocksSound{3}, app.BlocksFs{3}] = audioread('resources/sound/block3.wav');
            
            if strcmp(app.TriggerDropDown.Value, 'Audio + Trigger')
                % Add trigger channel to mono audio
                Fs = {};
                [app.BlocksTrigger{1}, Fs{1}] = audioread('resources/sound/block1.trig.wav');
                [app.BlocksTrigger{2}, Fs{2}] = audioread('resources/sound/block2.trig.wav');
                [app.BlocksTrigger{3}, Fs{3}] = audioread('resources/sound/block3.trig.wav');

                for i = 1:3
                    assert(app.BlocksFs{i} == Fs{i});
                    assert(size(app.BlocksSound{i}, 2) == 1);
                    assert(size(app.BlocksTrigger{i}, 2) == 1);
                    assert(size(app.BlocksSound{i}, 1) == size(app.BlocksTrigger{i}, 1));
                end

                app.BlocksSound{1} = cat(2, app.BlocksSound{1}, app.BlocksTrigger{1});
                app.BlocksSound{2} = cat(2, app.BlocksSound{2}, app.BlocksTrigger{2});
                app.BlocksSound{3} = cat(2, app.BlocksSound{3}, app.BlocksTrigger{3});
            end
        end

        % Value changed function: ReactionDropDown
        function ReactionDropDownValueChanged(app, event)
            value = app.ReactionDropDown.Value;
            
            if strcmp(value, "Keyboard")
                app.NeedsCalibration = true;
            else
                app.NeedsCalibration = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.Block1Button = uibutton(app.UIFigure, 'push');
            app.Block1Button.ButtonPushedFcn = createCallbackFcn(app, @Block1ButtonPushed, true);
            app.Block1Button.Text = 'Block 1';
            app.Redo1Button = uibutton(app.UIFigure, 'push');
            app.Redo1Button.ButtonPushedFcn = createCallbackFcn(app, @Redo1ButtonPushed, true);
            app.Redo1Button.Enable = 'off';
            app.Redo1Button.Text = 'Redo 1';
            app.Instruction = uilabel(app.UIFigure);
            app.Instruction.Visible = 'off';
            app.Instruction.Text = 'Press the button every time you hear the word "Objective"';
            app.TextLabel = uilabel(app.UIFigure);
            app.TextLabel.Text = 'Recording ID: ';
            app.Block3Button = uibutton(app.UIFigure, 'push');
            app.Block3Button.ButtonPushedFcn = createCallbackFcn(app, @Block3ButtonPushed, true);
            app.Block3Button.Text = 'Block 3';
            app.Redo3Button = uibutton(app.UIFigure, 'push');
            app.Redo3Button.ButtonPushedFcn = createCallbackFcn(app, @Redo3ButtonPushed, true);
            app.Redo3Button.Enable = 'off';
            app.Redo3Button.Text = 'Redo 3';
            app.Block2Button = uibutton(app.UIFigure, 'push');
            app.Block2Button.ButtonPushedFcn = createCallbackFcn(app, @Block2ButtonPushed, true);
            app.Block2Button.Text = 'Block 2';
            app.Redo2Button = uibutton(app.UIFigure, 'push');
            app.Redo2Button.ButtonPushedFcn = createCallbackFcn(app, @Redo2ButtonPushed, true);
            app.Redo2Button.Enable = 'off';
            app.Redo2Button.Text = 'Redo 2';
            app.RecordingIDLabel = uilabel(app.UIFigure);
            app.RecordingIDLabel.Text = '';
            app.Lamp1 = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.Lamp3 = uilamp(app.UIFigure);
            app.Description = uitextarea(app.UIFigure);
            app.Description.Editable = 'off';
            app.Description.Value = {'This task consists of three 5-minute blocks of speech.'; ''; 'Each block consists of a sequence of syllables that don''t necessarily make words.'; ''; 'Once in a while, three connected syllables will make a proper English word.'; ''; 'Listen carefully for those words.'; ''; 'One of those proper words is "Objective". Any time you hear "Objective", press the key provided to you.'; ''; 'At the end of each block, a multiple choice question will ask what words you did NOT hear during the task.'};
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Trisyllabic (v?)';
            app.QLabel = uilabel(app.UIFigure);
            app.QLabel.Enable = 'off';
            app.QLabel.Visible = 'off';
            app.QLabel.Text = 'Which of the following words were NOT spoken so far?';
            app.QSubmit = uibutton(app.UIFigure, 'push');
            app.QSubmit.ButtonPushedFcn = createCallbackFcn(app, @QSubmitButtonPushed, true);
            app.QSubmit.Enable = 'off';
            app.QSubmit.Visible = 'off';
            app.QSubmit.Text = 'Submit';
            app.QCheckBox1 = uicheckbox(app.UIFigure);
            app.QCheckBox1.Enable = 'off';
            app.QCheckBox1.Visible = 'off';
            app.QCheckBox1.Text = 'Option 1';
            app.QCheckBox2 = uicheckbox(app.UIFigure);
            app.QCheckBox2.Enable = 'off';
            app.QCheckBox2.Visible = 'off';
            app.QCheckBox2.Text = 'Option 2';
            app.QCheckBox3 = uicheckbox(app.UIFigure);
            app.QCheckBox3.Enable = 'off';
            app.QCheckBox3.Visible = 'off';
            app.QCheckBox3.Text = 'Option 3';
            app.TriggerDropDown = uidropdown(app.UIFigure);
            app.TriggerDropDown.Items = {'Audio only', 'Audio + Trigger'};
            app.TriggerDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerDropDownValueChanged, true);
            app.TriggerDropDown.Value = 'Audio + Trigger';
            app.ReactionDropDown = uidropdown(app.UIFigure);
            app.ReactionDropDown.Items = {'External button', 'Keyboard'};
            app.ReactionDropDown.ValueChangedFcn = createCallbackFcn(app, @ReactionDropDownValueChanged, true);
            app.ReactionDropDown.Value = 'External button';
            app.Fixation = uiimage(app.UIFigure);
            app.Fixation.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Trisyllabic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end